classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup1              matlab.ui.container.TabGroup
        Tab_QAM                matlab.ui.container.Tab
        Axe_QAM_16             matlab.ui.control.UIAxes
        Tab_Bruit              matlab.ui.container.Tab
        Tab_BitAllocation      matlab.ui.container.Tab
        Axe_BitAlloc           matlab.ui.control.UIAxes
        Tab_Egalisation        matlab.ui.container.Tab
        Axe_fft_dmt_frame      matlab.ui.control.UIAxes
        Axe_fft_channel_frame  matlab.ui.control.UIAxes
        Axe_fft_with_prefixe   matlab.ui.control.UIAxes
        Tab_Channel1           matlab.ui.container.Tab
        Axe_RepImp             matlab.ui.control.UIAxes
        Axe_RepFreq            matlab.ui.control.UIAxes
        Tab_Channel2           matlab.ui.container.Tab
        Axe_EstimChannel       matlab.ui.control.UIAxes
        Axe_EstimNoise         matlab.ui.control.UIAxes
        RateLabel              matlab.ui.control.Label
        EditField_Rate         matlab.ui.control.NumericEditField
        UsefulRateLabel        matlab.ui.control.Label
        EditField_Useful_Rate  matlab.ui.control.NumericEditField
        LaunchButton           matlab.ui.control.Button
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EditField_Rate.Value=0;
            app.EditField_Useful_Rate.Value=0;
        end

        % Button pushed function: LaunchButton
        function LaunchButtonPushed(app, event)
            % launch the simulation
           [rep_imp, rep_freq_tot, SNR, alloc, dmt_frame, channel_frame, rate, useful_rate, estimated_channel, estimated_noise] = ADSL_simulation();
         
            
            % plot estimated_channel
            plot(app.Axe_EstimChannel, abs(estimated_channel));
            title(app.Axe_EstimChannel,'Channell Estimation');
            
            % plot estimatd_noise
            plot(app.Axe_EstimNoise, abs(estimated_noise));
            title(app.Axe_EstimNoise,'Noise Estimation');
            
            % plot rep_imp
            plot(app.Axe_RepImp, abs(rep_imp));
            title(app.Axe_RepImp,'Reponse Impulsionnel');
            
            % plot rep_freq_tot
            plot(app.Axe_RepFreq, abs(rep_freq_tot));
            title(app.Axe_RepFreq,'Reponse Frequatiel');
            
            % plot BitAllocation
            
            SNR=SNR(1:30);
            alloc=alloc(1:30);
            
            yyaxis(app.Axe_BitAlloc,'left');
            bar(app.Axe_BitAlloc,alloc,1,'c');
            axis(app.Axe_BitAlloc,[0 30 0 20]);
            title(app.Axe_BitAlloc,'Channell Allocation Data')
            xlabel(app.Axe_BitAlloc,'Channel Index');
            ylabel(app.Axe_BitAlloc,'Bit Allocation');
            
            yyaxis(app.Axe_BitAlloc,'right');
            plot(app.Axe_BitAlloc,SNR);
            axis(app.Axe_BitAlloc,[0 30 -200 200]);
            ylabel(app.Axe_BitAlloc,'SNR/dB');
            
            % plot QAM
            
%             after_canal=cell2mat(after_canal(2:length(after_canal)));
%             x = real(after_canal);
%             y = imag(after_canal);
            
%             scatter(app.Axe_QAM_16,x,y,2);
%             title(app.Axe_QAM_16,'QAM_16');
            
            % plot fft dmt_frame
            
            plot(app.Axe_fft_dmt_frame,abs(fft(dmt_frame)));
            title(app.Axe_fft_dmt_frame,'fft dmt_frame');
            
            % plot fft channel_frame
            
            plot(app.Axe_fft_channel_frame,abs(fft(channel_frame)));
            title(app.Axe_fft_channel_frame,'fft channel_frame');
            
            % plot fft dmt_frame
            
%             plot(app.Axe_fft_with_prefixe,abs(fft(with_prefixe)));
%             title(app.Axe_fft_with_prefixe,'fft with_prefixe');
            
            % update rate and useful rate
            
            app.EditField_Rate.Value=rate;
            app.EditField_Useful_Rate.Value=useful_rate;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup1 = uitabgroup(app.UIFigure);
            app.Tab_QAM = uitab(app.TabGroup1);
            app.Tab_QAM.Title = 'QAM';
            app.Tab_QAM.Units = 'pixels';
            app.Axe_QAM_16 = uiaxes(app.Tab_QAM);
            title(app.Axe_QAM_16, 'Title');
            xlabel(app.Axe_QAM_16, 'X');
            ylabel(app.Axe_QAM_16, 'Y');
            app.Tab_Bruit = uitab(app.TabGroup1);
            app.Tab_Bruit.Title = 'Bruit';
            app.Tab_Bruit.Units = 'pixels';
            app.Tab_BitAllocation = uitab(app.TabGroup1);
            app.Tab_BitAllocation.Title = 'BitAllocation';
            app.Tab_BitAllocation.Units = 'pixels';
            app.Axe_BitAlloc = uiaxes(app.Tab_BitAllocation);
            title(app.Axe_BitAlloc, 'BitAllocation');
            xlabel(app.Axe_BitAlloc, 'X');
            ylabel(app.Axe_BitAlloc, 'Y');
            app.Tab_Egalisation = uitab(app.TabGroup1);
            app.Tab_Egalisation.Title = 'Egalisation';
            app.Tab_Egalisation.Units = 'pixels';
            app.Axe_fft_dmt_frame = uiaxes(app.Tab_Egalisation);
            title(app.Axe_fft_dmt_frame, 'Title');
            xlabel(app.Axe_fft_dmt_frame, 'X');
            ylabel(app.Axe_fft_dmt_frame, 'Y');
            app.Axe_fft_channel_frame = uiaxes(app.Tab_Egalisation);
            title(app.Axe_fft_channel_frame, 'Title');
            xlabel(app.Axe_fft_channel_frame, 'X');
            ylabel(app.Axe_fft_channel_frame, 'Y');
            app.Axe_fft_with_prefixe = uiaxes(app.Tab_Egalisation);
            title(app.Axe_fft_with_prefixe, 'Title');
            xlabel(app.Axe_fft_with_prefixe, 'X');
            ylabel(app.Axe_fft_with_prefixe, 'Y');
            app.Tab_Channel1 = uitab(app.TabGroup1);
            app.Tab_Channel1.Title = 'Channel';
            app.Tab_Channel1.Units = 'pixels';
            app.Axe_RepImp = uiaxes(app.Tab_Channel1);
            title(app.Axe_RepImp, 'Title');
            xlabel(app.Axe_RepImp, 'X');
            ylabel(app.Axe_RepImp, 'Y');
            app.Axe_RepFreq = uiaxes(app.Tab_Channel1);
            title(app.Axe_RepFreq, 'Title');
            xlabel(app.Axe_RepFreq, 'X');
            ylabel(app.Axe_RepFreq, 'Y');
            app.Tab_Channel2 = uitab(app.TabGroup1);
            app.Tab_Channel2.Title = 'Channel';
            app.Tab_Channel2.Units = 'pixels';
            app.Axe_EstimChannel = uiaxes(app.Tab_Channel2);
            title(app.Axe_EstimChannel, 'Title');
            xlabel(app.Axe_EstimChannel, 'X');
            ylabel(app.Axe_EstimChannel, 'Y');
            app.Axe_EstimNoise = uiaxes(app.Tab_Channel2);
            title(app.Axe_EstimNoise, 'Title');
            xlabel(app.Axe_EstimNoise, 'X');
            ylabel(app.Axe_EstimNoise, 'Y');
            app.RateLabel = uilabel(app.UIFigure);
            app.RateLabel.Text = 'Rate';
            app.EditField_Rate = uieditfield(app.UIFigure, 'numeric');
            app.UsefulRateLabel = uilabel(app.UIFigure);
            app.UsefulRateLabel.Text = 'UsefulRate';
            app.EditField_Useful_Rate = uieditfield(app.UIFigure, 'numeric');
            app.LaunchButton = uibutton(app.UIFigure, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchButtonPushed, true);
            app.LaunchButton.Text = 'Launch';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end